classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ChooseInputPanel           matlab.ui.container.Panel
        orLabel                    matlab.ui.control.Label
        LoadImageButton            matlab.ui.control.Button
        ChooseBallotImagefromDatasetDropDownLabel  matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        RunPipelineButton          matlab.ui.control.Button
        ViennaElection2020BallotCountingToolLabel  matlab.ui.control.Label
        ChosenImagePanel           matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        ResultPanel                matlab.ui.container.Panel
        ChosenPartysTextAreaLabel  matlab.ui.control.Label
        ChosenPartysTextArea       matlab.ui.control.TextArea
        ValidityTextAreaLabel      matlab.ui.control.Label
        ValidityTextArea           matlab.ui.control.TextArea
        ErrorsTextAreaLabel        matlab.ui.control.Label
        ErrorsTextArea             matlab.ui.control.TextArea
        ExecutiontimePanel         matlab.ui.container.Panel
        timeLabel                  matlab.ui.control.Label
        secLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        ballotFilename
        Timer
        
        timer_start_time
        timer_stopped
        
   end
    
    methods (Access = private)
        
        function TimerFcn(app,~,~)
            if ~app.timer_stopped
                time_elapsed = etime(clock,app.timer_start_time);
                app.timeLabel.Text = num2str(abs(time_elapsed));
            end
        end
        
        function TimerStartFcn(app,~,~)
            app.timer_start_time = clock;
            app.timer_stopped = false;
        end
        
        function TimerStopFcn(app,~,~)
            app.timer_stopped = true;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Get existing files in folder
            ballotFiles = BallotFilenames();
            
            app.DropDown.Items = ballotFiles(:,2);
            app.DropDown.ItemsData = ballotFiles(:,1);
            
            app.ballotFilename = app.DropDown.Value;
            app.Image.ImageSource = imread(app.ballotFilename);
            
            app.Timer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.01, ...                
                'BusyMode', 'queue',...            
                'TimerFcn', @app.TimerFcn,...
                'StartFcn', @app.TimerStartFcn,...
                'StopFcn', @app.TimerStopFcn);      
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.ballotFilename = app.DropDown.Value;
            app.Image.ImageSource = imread(app.ballotFilename);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.png',' Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               app.Image.ImageSource = imread(fname);
               app.ballotFilename = fname;
            end
        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)
            app.RunPipelineButton.Text = "Pipeline running...";
            app.DropDown.Enable = false;
            app.LoadImageButton.Enable = false;
            app.RunPipelineButton.Enable = false;
            app.ChosenPartysTextArea.Enable = false;
            app.ValidityTextArea.Enable = false;
            app.ErrorsTextArea.Enable = false;
            
            app.timeLabel.Text = "0.0";
            
            start(app.Timer);
            [~, validity, choice, errors] = Pipeline(Templ(), "", [app.ballotFilename, "gui"]);
            stop(app.Timer);
            delete(app.Timer);
            
            app.Timer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.1, ...                     % Period is 1 second
                'BusyMode', 'drop',...              % Queue timer callbacks when busy
                'TimerFcn', @app.TimerFcn,...
                'StartFcn', @app.TimerStartFcn,...
                'StopFcn', @app.TimerStopFcn);      % Specify callback function

            app.ChosenPartysTextArea.Value = choice;
            app.ValidityTextArea.Value = validity;  
            app.ErrorsTextArea.Value = errors;
            
            app.DropDown.Enable = true;
            app.LoadImageButton.Enable = true;
            app.RunPipelineButton.Enable = true;
            app.ChosenPartysTextArea.Enable = true;
            app.ValidityTextArea.Enable = true;
            app.ErrorsTextArea.Enable = true;
            app.RunPipelineButton.Text = "Run pipeline";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~app.timer_stopped
                stop(app.Timer);
                delete(app.Timer);
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ChooseInputPanel = uipanel(app.UIFigure);
            app.ChooseInputPanel.Title = 'Choose Input';
            app.orLabel = uilabel(app.ChooseInputPanel);
            app.orLabel.Text = 'or';
            app.LoadImageButton = uibutton(app.ChooseInputPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Custom Ballot Image';
            app.ChooseBallotImagefromDatasetDropDownLabel = uilabel(app.ChooseInputPanel);
            app.ChooseBallotImagefromDatasetDropDownLabel.Text = 'Choose Ballot Image from Dataset';
            app.DropDown = uidropdown(app.ChooseInputPanel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.RunPipelineButton = uibutton(app.UIFigure, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.ViennaElection2020BallotCountingToolLabel = uilabel(app.UIFigure);
            app.ViennaElection2020BallotCountingToolLabel.Text = 'Vienna Election 2020 Ballot Counting Tool';
            app.ChosenImagePanel = uipanel(app.UIFigure);
            app.ChosenImagePanel.Title = 'Chosen Image';
            app.Image = uiimage(app.ChosenImagePanel);
            app.ResultPanel = uipanel(app.UIFigure);
            app.ResultPanel.Title = 'Result';
            app.ChosenPartysTextAreaLabel = uilabel(app.ResultPanel);
            app.ChosenPartysTextAreaLabel.Enable = 'off';
            app.ChosenPartysTextAreaLabel.Text = 'Chosen Partys';
            app.ChosenPartysTextArea = uitextarea(app.ResultPanel);
            app.ChosenPartysTextArea.Editable = 'off';
            app.ChosenPartysTextArea.Enable = 'off';
            app.ValidityTextAreaLabel = uilabel(app.ResultPanel);
            app.ValidityTextAreaLabel.Enable = 'off';
            app.ValidityTextAreaLabel.Text = 'Validity';
            app.ValidityTextArea = uitextarea(app.ResultPanel);
            app.ValidityTextArea.Editable = 'off';
            app.ValidityTextArea.Enable = 'off';
            app.ErrorsTextAreaLabel = uilabel(app.ResultPanel);
            app.ErrorsTextAreaLabel.Enable = 'off';
            app.ErrorsTextAreaLabel.Text = 'Errors';
            app.ErrorsTextArea = uitextarea(app.ResultPanel);
            app.ErrorsTextArea.Editable = 'off';
            app.ErrorsTextArea.Enable = 'off';
            app.ExecutiontimePanel = uipanel(app.UIFigure);
            app.ExecutiontimePanel.Title = 'Execution time';
            app.timeLabel = uilabel(app.ExecutiontimePanel);
            app.timeLabel.Text = '0.0';
            app.secLabel = uilabel(app.ExecutiontimePanel);
            app.secLabel.Text = 'sec';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end